classdef ADCS_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCSSimulatorUIFigure           matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveGUIStateMenu                matlab.ui.container.Menu
        LoadGUIStateMenu                matlab.ui.container.Menu
        ResettoDefaultStatesMenu        matlab.ui.container.Menu
        GenerateMATLABPlotsMenu         matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CloseAllFiguresMenu             matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TopTab                          matlab.ui.container.TabGroup
        ScenarioSetupTab                matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        RunSimulationButton             matlab.ui.control.Button
        LoadSimulinkModelButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        BusConfigurationTab             matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        ConfigureCoPButton              matlab.ui.control.Button
        MassPropertiesLabel             matlab.ui.control.Label
        Izz                             matlab.ui.control.NumericEditField
        Izy                             matlab.ui.control.NumericEditField
        Iyz                             matlab.ui.control.NumericEditField
        Iyy                             matlab.ui.control.NumericEditField
        Ixz                             matlab.ui.control.NumericEditField
        Ixy                             matlab.ui.control.NumericEditField
        Izx                             matlab.ui.control.NumericEditField
        Row3kgm2Label                   matlab.ui.control.Label
        Iyx                             matlab.ui.control.NumericEditField
        Row2kgm2Label                   matlab.ui.control.Label
        Ixx                             matlab.ui.control.NumericEditField
        Row1kgm2Label                   matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        ConfigureMoIButton              matlab.ui.control.Button
        BusPropertiesLabel              matlab.ui.control.Label
        CoMZEditField                   matlab.ui.control.NumericEditField
        CoMZEditFieldLabel              matlab.ui.control.Label
        ZLengthmEditField               matlab.ui.control.NumericEditField
        ZLengthmEditFieldLabel          matlab.ui.control.Label
        YLengthmEditField               matlab.ui.control.NumericEditField
        YLengthmEditFieldLabel          matlab.ui.control.Label
        XLengthmEditField               matlab.ui.control.NumericEditField
        XLengthmEditFieldLabel          matlab.ui.control.Label
        CoMYEditField                   matlab.ui.control.NumericEditField
        CoMYEditFieldLabel              matlab.ui.control.Label
        CoMXEditField                   matlab.ui.control.NumericEditField
        CoMXEditFieldLabel              matlab.ui.control.Label
        BusViewer                       matlab.ui.control.UIAxes
        InitialConditionsTab            matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        TimeofPerigeePassagesecEditField  matlab.ui.control.NumericEditField
        TimeofPerigeePassagesecEditFieldLabel  matlab.ui.control.Label
        ArgumentofPergieedegEditField   matlab.ui.control.NumericEditField
        ArgumentofPergieedegEditFieldLabel  matlab.ui.control.Label
        RAANdegEditField                matlab.ui.control.NumericEditField
        RAANdegEditFieldLabel           matlab.ui.control.Label
        InclinationdegEditField         matlab.ui.control.NumericEditField
        InclinationdegEditFieldLabel    matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel      matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        OrbitSelectionLabel             matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        omega_3circsEditField           matlab.ui.control.NumericEditField
        omega_3circsEditFieldLabel      matlab.ui.control.Label
        omega_2circsEditField           matlab.ui.control.NumericEditField
        omega_2circsEditFieldLabel      matlab.ui.control.Label
        omega_1circsEditField           matlab.ui.control.NumericEditField
        omega_1circsLabel               matlab.ui.control.Label
        q_4EditField                    matlab.ui.control.NumericEditField
        q_4EditFieldLabel               matlab.ui.control.Label
        q_3EditField                    matlab.ui.control.NumericEditField
        q_3EditFieldLabel               matlab.ui.control.Label
        q_2EditField                    matlab.ui.control.NumericEditField
        q_2EditFieldLabel               matlab.ui.control.Label
        q_1EditField                    matlab.ui.control.NumericEditField
        q_1EditFieldLabel               matlab.ui.control.Label
        SpacecraftAttitudeandMotionLabel  matlab.ui.control.Label
        HardwareTab                     matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        GPSsigma_GPSdegEditField        matlab.ui.control.NumericEditField
        GPSsigma_GPSdegEditFieldLabel   matlab.ui.control.Label
        GyroBiassigma_bradsEditField    matlab.ui.control.NumericEditField
        GyroBiassigma_bradsEditFieldLabel  matlab.ui.control.Label
        GyroWalksigma_gradsEditField    matlab.ui.control.NumericEditField
        GyroWalksigma_gradsEditFieldLabel  matlab.ui.control.Label
        Magnetometersigma_mdegEditField  matlab.ui.control.NumericEditField
        Magnetometersigma_mdegEditFieldLabel  matlab.ui.control.Label
        SunSensorsigma_sdegEditField    matlab.ui.control.NumericEditField
        SunSensorsigma_sdegEditFieldLabel  matlab.ui.control.Label
        SensorsLabel                    matlab.ui.control.Label
        GridLayout14                    matlab.ui.container.GridLayout
        WheelInertiakgm2EditField       matlab.ui.control.NumericEditField
        WheelInertiakgm2Label           matlab.ui.control.Label
        CrosssectionAream2EditField     matlab.ui.control.NumericEditField
        CrosssectionAream2EditFieldLabel  matlab.ui.control.Label
        CoilTurnsEditField              matlab.ui.control.NumericEditField
        CoilTurnsEditFieldLabel         matlab.ui.control.Label
        TorqueRodsAlignmentEditField    matlab.ui.control.EditField
        TorqueRodsAlignmentEditFieldLabel  matlab.ui.control.Label
        WheelsSpeedLabel                matlab.ui.control.Label
        WheelsSpeedradsEditField        matlab.ui.control.EditField
        WheelsAlignmentEditField        matlab.ui.control.EditField
        WheelsAlignmentEditFieldLabel   matlab.ui.control.Label
        ActuatorsLabel                  matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        EpochTimeHHMMSSLabel            matlab.ui.control.Label
        EpochTimeHHMMSSEditField        matlab.ui.control.EditField
        EpochDateDatePicker             matlab.ui.control.DatePicker
        EpochDateDatePickerLabel        matlab.ui.control.Label
        TimeStepsecEditField            matlab.ui.control.NumericEditField
        TimeStepsecEditFieldLabel       matlab.ui.control.Label
        ControlModeDropDown             matlab.ui.control.DropDown
        ControlModeDropDownLabel        matlab.ui.control.Label
        PointingModeDropDown            matlab.ui.control.DropDown
        PointingModeDropDownLabel       matlab.ui.control.Label
        EnableperturbationtorquesCheckBox  matlab.ui.control.CheckBox
        EnablegroundtruthvaluesCheckBox  matlab.ui.control.CheckBox
        EnablenavigationfilterCheckBox  matlab.ui.control.CheckBox
        SimulationTimesecEditField      matlab.ui.control.NumericEditField
        SimulationTimesecEditFieldLabel  matlab.ui.control.Label
        GNCConfigurationTab             matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17_3                  matlab.ui.container.GridLayout
        TunePDControllerButton          matlab.ui.control.Button
        GNCSamplingRatesecEditField     matlab.ui.control.NumericEditField
        GNCSamplingRatesecEditFieldLabel  matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        BdotControllerLabel             matlab.ui.control.Label
        PDControllerLabel               matlab.ui.control.Label
        InputthecontrollergainsforthePDcontrollerasKp1000Kp2000Kp3Label  matlab.ui.control.Label
        K_wEditField                    matlab.ui.control.NumericEditField
        K_wEditFieldLabel               matlab.ui.control.Label
        K_dEditFieldLabel               matlab.ui.control.Label
        K_dEditField                    matlab.ui.control.EditField
        K_pEditFieldLabel               matlab.ui.control.Label
        K_pEditField                    matlab.ui.control.EditField
        ControllerTuningLabel           matlab.ui.control.Label
        AnimationTab                    matlab.ui.container.Tab
        AnimationGrid                   matlab.ui.container.GridLayout
        AnimationControlPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        OrbitViewer                     matlab.ui.control.UIAxes
        AttitudeViewer                  matlab.ui.control.UIAxes
        Slider                          matlab.ui.control.Slider
        GridLayout17                    matlab.ui.container.GridLayout
        PlotsTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        wAxes                           matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        MomentumAxes                    matlab.ui.control.UIAxes
        MagMomentAxes                   matlab.ui.control.UIAxes
        AttAxes                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = public)
        YYYY double   = 0;
        DD double     = 0;
        MM double     = 0;
        HH double     = 00;
        MIN double    = 00;
        SEC double    = 000;
        dYear double = 0;
        JD0 double = 0;
        
        rE double         = 6378.14;                         % Earth radius, km
        wE double         = [0 0 15.04*(pi/180)*(1/3600)];   % Earth rotation speed, rad/s
        mu double         = 398600.4418;                     % Gravitational parameter, km^3/s^2
        p  double         = 0.00000451;                      % Solar pressure constant, N/m^2
        CD double         = 2.3;                             % Drag Coefficent
        eta double        = 0.3;                             % Absorption Coefficient
        m (3,1) double  = [0.005; 0.005; 0.005];           % Magnetic Dipole

        xlen double = 0.1;
        ylen double = 0.1;
        zlen double = 0.1;
        cx   double = 0;
        cy   double = 0;
        cz   double = 0;
        J_body (3,3) double = zeros(3,3);
        I_xx double = 0;
        I_xy double = 0;
        I_xz double = 0;      
        I_yx double = 0;
        I_yy double = 0;
        I_yz double = 0;
        I_zx double = 0;
        I_zy double = 0;
        I_zz double = 0;
        simfile char = "";
        location char = "";
        CoP (6,3) double = [5,0,0;0,5,0;0,0,17;-5,0,0;0,-5,0;0,0,-17];
        n_list (6,3) double = zeros(6,3);
        A_list (6,1) double = zeros(6,1);
        q1_ini double = 0;
        q2_ini double = 0;
        q3_ini double = 0;
        q4_ini double = 0;
        wB_1 double = 0;
        wB_2 double = 0;
        wB_3 double = 0;
        a double = 0;
        e double = 0;
        i double = 0;
        RAAN double = 0;
        omega double = 0;
        tp double = 0;
        sigma_s double = 0;
        sigma_m double = 0;
        sigma_g double = 0;
        sigma_b double = 0;
        sigma_GPS double = 0;
        r_I_ini (3,1) double = zeros(3,1);
        v_I_ini (3,1) double = zeros(3,1);
        A_w (3,3) double = zeros(3,3);
        A_m (3,3) double = zeros(3,3);
        wW1 double = 0;
        wW2 double = 0;
        wW3 double = 0;
        ar double = 0;
        turns double = 0;
        pointingMode double = 0;
        controlMode double = 0;
        tspan double = 0;
        baseRate double = 0;
        dt double = 0;
        useFilter logical = false;
        truevals logical = false;
        ext_torque logical = false;
        R (6,6) = zeros(6,6);
        n double = 0;
        K_p (3,3) double = 0.000125*eye(3);
        K_d (3,3) double = 0.0075*eye(3);
        K_w double = 0;
        period double = 0;
        mode double
        mass double
        satPatch double
        simOut Simulink.SimulationOutput
        plot_graphs logical

        animTimer timer
        step (1,1) double = 1
        rate (1,1) double = 1
    
        % orbit viewer handles (left)
        hSat          matlab.graphics.chart.primitive.Line
        hB1           matlab.graphics.chart.primitive.Quiver
        hB2           matlab.graphics.chart.primitive.Quiver
        hB3           matlab.graphics.chart.primitive.Quiver
    
        % body viewer handles (right)
        hPatch        matlab.graphics.primitive.Patch
        hBx           matlab.graphics.chart.primitive.Quiver
        hBy           matlab.graphics.chart.primitive.Quiver
        hBz           matlab.graphics.chart.primitive.Quiver
    
        % cube vertices (body frame) cached
        V_body double
        F double
        speed double

        t double = [];                 % time (Nx1)
        att double = [];               % [roll pitch yaw] (Nx3)
        rates double = [];             % [wx wy wz] (Nx3)
        h_w double = [];
        magM double = [];
        err double = [];
        rI double = [];                 % [N x 3] ECI position (km)
        q double = [];                  % [N x 4] quaternion
        sI double = [];                 % [N x 3] optional Sun vector (ECI)
    end

    methods (Access = public)
        
        % Initializing all the values
        function setvalues(app)
            app.XLengthmEditFieldValueChanged();
            app.YLengthmEditFieldValueChanged();
            app.ZLengthmEditFieldValueChanged();
            app.IxxValueChanged();
            app.IyxValueChanged();
            app.IzxValueChanged();
            app.IxyValueChanged();
            app.IxzValueChanged();
            app.IyyValueChanged();
            app.IyzValueChanged();
            app.IzyValueChanged();
            app.IzzValueChanged();
            app.q_1EditFieldValueChanged();
            app.q_2EditFieldValueChanged();
            app.q_3EditFieldValueChanged();
            app.q_4EditFieldValueChanged();
            app.omega_1circsEditFieldValueChanged();
            app.omega_2circsEditFieldValueChanged();
            app.omega_3circsEditFieldValueChanged();
            app.AltitudekmEditFieldValueChanged();
            app.EccentricityEditFieldValueChanged();
            app.InclinationdegEditFieldValueChanged();
            app.RAANdegEditFieldValueChanged();
            app.ArgumentofPergieedegEditFieldValueChanged();
            app.TimeofPerigeePassagesecEditFieldValueChanged();
            app.CoMXEditFieldValueChanged();
            app.CoMYEditFieldValueChanged();
            app.CoMZEditFieldValueChanged();
            app.SunSensorsigma_sdegEditFieldValueChanged();
            app.Magnetometersigma_mdegEditFieldValueChanged();
            app.GyroWalksigma_gradsEditFieldValueChanged();
            app.GyroBiassigma_bradsEditFieldValueChanged();
            app.GPSsigma_GPSdegEditFieldValueChanged();           
            app.WheelsAlignmentEditFieldValueChanged();
            app.WheelsSpeedradsEditFieldValueChanged();
            app.TorqueRodsAlignmentEditFieldValueChanged();
            app.CoilTurnsEditFieldValueChanged();
            app.CrosssectionAream2EditFieldValueChanged();
            app.PointingModeDropDownValueChanged();
            app.ControlModeDropDownValueChanged();
            app.SimulationTimesecEditFieldValueChanged();
            app.TimeStepsecEditFieldValueChanged();
            app.GNCSamplingRatesecEditFieldValueChanged();
            app.EnablenavigationfilterCheckBoxValueChanged();
            app.EnablegroundtruthvaluesCheckBoxValueChanged();
            app.EnableperturbationtorquesCheckBoxValueChanged();
            app.K_pEditFieldValueChanged();
            app.K_dEditFieldValueChanged();
            app.K_wEditFieldValueChanged();
            app.dtime();
            app.WheelInertiakgm2EditFieldValueChanged();
            app.MasskgEditFieldValueChanged();
            app.initializeConstants();
            app.SliderValueChanged();
            app.EpochDateDatePickerValueChanged();
            app.EpochTimeHHMMSSEditFieldValueChanged();
            app.SliderValueChanged();
            
            % Assigning initialization in base workspace
            assignin("base","K_p",app.K_p);
            assignin("base","K_d",app.K_d);
            assignin("base","COP",app.CoP);
            % run('init.m'); % Script to save everything in the workspace
        end

        function drawEarth(app)
            ax = app.OrbitViewer;
            hold(ax,'on'); grid(ax,'on'); axis(ax,'equal');
            xlim(ax,[-8e3 8e3]); ylim(ax,[-8e3 8e3]); zlim(ax,[-8e3 8e3]);
            % Earth textured sphere
            [x,y,z] = sphere(20);

            hEarth = surf(ax, app.rE*x, app.rE*y, app.rE*z, ...
                'FaceColor',[0 0.1 0.1], 'FaceAlpha', 1, 'EdgeAlpha',...
                0.5, 'EdgeColor','w');

        end

        function drawAtt(app)
            ax = app.AttitudeViewer; axis(ax,'equal'); cla(ax);
           
            app.satPatch = patch(ax, 'Vertices', app.V_body, ...
                                   'Faces', app.F, ...
                                   'FaceColor', [0 0.1 0.1], ...
                                   'FaceAlpha', 0.75, ...
                                   'EdgeColor', 'w');

            Rview = app.zlen;
            
            xlim(ax,[-Rview Rview])
            ylim(ax,[-Rview Rview])
            zlim(ax,[-Rview Rview])

            hold(ax, 'on');

            % Adding BoF ref. frame axes
            xscl = 0.12;
            yscl = xscl;
            zscl = 0.25;
            quiver3(ax,0,0,0,xscl,0,0,1,'Color',[1 0 0],'LineWidth',0.5);
            quiver3(ax,0,0,0,0,yscl,0,1,'Color',[0 1 0],'LineWidth',0.5);
            quiver3(ax,0,0,0,0,0,zscl,1,'Color',[0 0 1],'LineWidth',0.5);

        end

        
        % Creates the bus for the bus setup panel (can also be used for
        % plotting orbit and attitude)
        function drawsc(app)

            ax = app.BusViewer; axis(ax,'equal'); cla(ax);
            app.V_body = [-app.xlen/2,  -app.ylen/2,  -app.zlen/2;
                         -app.xlen/2,  -app.ylen/2,   app.zlen/2;
                         -app.xlen/2,   app.ylen/2,  -app.zlen/2;
                         -app.xlen/2,   app.ylen/2,   app.zlen/2;
                          app.xlen/2,  -app.ylen/2,  -app.zlen/2;
                          app.xlen/2,  -app.ylen/2,   app.zlen/2;
                          app.xlen/2,   app.ylen/2,  -app.zlen/2;
                          app.xlen/2,   app.ylen/2,   app.zlen/2];
            app.F = [1 2 4 3    % -X face
                5 6 8 7     % +X face
                1 2 6 5     % -Y face
                3 4 8 7     % +Y face
                1 3 7 5     % -Z face
                2 4 8 6];   % +Z face

            
            hPatch = patch(ax, 'Vertices', app.V_body, ...
                               'Faces', app.F, ...
                               'FaceColor', [0 0.1 0.1], ...
                               'FaceAlpha', 0.75, ...
                               'EdgeColor', 'w');
            
            padXY = 0.1;
            padZ  = 0.15;
            
            xlim(ax, [-app.xlen/2 - padXY,  app.xlen/2 + padXY]);
            ylim(ax, [-app.ylen/2 - padXY,  app.ylen/2 + padXY]);
            zlim(ax, [-app.zlen/2 - padZ,   app.zlen/2 + padZ]);

            hold(ax,'on')

            plot3(ax, app.cz, app.cy, app.cx, 'ro', LineWidth=2)
            
            % Adding BoF ref. frame axes
            xscl = 0.12;
            yscl = xscl;
            zscl = 0.25;
            quiver3(ax,0,0,0,xscl,0,0,1,'Color',[1 0 0],'LineWidth',0.5);
            quiver3(ax,0,0,0,0,yscl,0,1,'Color',[0 1 0],'LineWidth',0.5);
            quiver3(ax,0,0,0,0,0,zscl,1,'Color',[0 0 1],'LineWidth',0.5);

            % Plotting the CoP
            % for k = 1:6
            %     plot3(ax,app.CoP(k,1),app.CoP(k,2),app.CoP(k,3),'co');
            % end

            app.n_list  = [1 0 0; 0 1 0; 0 0 1; -1 0 0; 0 -1 0; 0 0 -1];            % Surface normal vectors
            app.A_list  = [app.xlen*app.ylen; app.xlen*app.ylen; app.ylen*app.zlen;
                           app.xlen*app.ylen; app.xlen*app.ylen; app.ylen*app.zlen] * 1e-04;       % Defining panel areas in m2
            assignin('base','n_list',app.n_list);
            assignin('base','A_list',app.A_list);
        end

        % Create the inertia matrix
        function makeJ(app)
            app.J_body = [app.I_xx, app.I_xy, app.I_xz;
                          app.I_yz, app.I_yy, app.I_yz;
                          app.I_zx, app.I_zy, app.I_zz];
            assignin('base','J_body',app.J_body);
        end

        % Waitbox for running simulation
        function d = CustomWaitBox(app,Message) 
            fig = app.ADCSSimulatorUIFigure; 
            d = uiprogressdlg(fig,'Title',Message, 'Indeterminate','on'); 
            drawnow
        end

        % Initial state from orbital elements
        function oe2rv(app)
            
            app.n = sqrt(app.mu/app.a^3);
            app.period = 2*pi/app.n;
            % Calculate true anomaly 
            M     = app.n * (0-app.tp);
            E_pre = M + sin(M)*app.e;
        
            for k = 1:100
                E_next = E_pre - (E_pre - app.e*sin(E_pre) - M)/(1 - app.e*cos(E_pre));
                E_pre = E_next;
            end
            
            eano = E_next;
        
            tano = 2 * atan(tan(eano/2)*sqrt((1+app.e)/(1-app.e))); % rad            
        
            % Position & velocity in Perifocal frame
            p = app.a * (1 - app.e^2);
            r_P = (p / (1 + app.e*cos(tano))) * [cos(tano); sin(tano); 0];
            v_P = sqrt(app.mu / p) * [-sin(tano); app.e + cos(tano); 0];
        
            % Rotation Sequence
            C3_omega = [cos(app.omega), sin(app.omega), 0; -sin(app.omega), cos(app.omega), 0; 0, 0, 1];
            C1_i = [1, 0, 0; 0, cos(app.i), sin(app.i); 0, -sin(app.i), cos(app.i)];
            C3_RAAN = [cos(app.RAAN), sin(app.RAAN), 0; -sin(app.RAAN), cos(app.RAAN), 0; 0, 0, 1];
        
            C_PI = C3_omega * C1_i * C3_RAAN;
        
            C_IP = C_PI';
           
            % Convert to ECI
            app.r_I_ini = C_IP * r_P;
            app.v_I_ini = C_IP * v_P;

            assignin('base','r_I_ini',app.r_I_ini)
            assignin('base','v_I_ini',app.v_I_ini)
            assignin('base','n',app.n)
            assignin('base','period',app.period)

        end

        function dtime(app)
            app.dYear = decyear(app.YYYY, app.MM, app.DD);
            app.JD0 = juliandate(app.YYYY, app.MM, app.DD, app.HH, app.MIN, app.SEC);
            assignin("base","dYear",app.dYear)
            assignin("base","JD0",app.JD0)
        end

        function buildR(app)
            app.R = blkdiag(app.sigma_s^2*eye(3), app.sigma_m^2*eye(3));
            assignin("base","R",app.R)
        end

        function initializeConstants(app)
            assignin('base','rE',app.rE);
            assignin('base','wE',app.wE);
            assignin('base','mu',app.mu);
            assignin('base','p',app.p);
            assignin('base','CD',app.CD);
            assignin('base','eta',app.eta);
            assignin('base','mag_moment', app.m);
        end
        
        function initAnimation(app)
            % Timing
            app.step = 1;
            app.rate = 10; 
        
            % Orbit Viewer
            axL = app.OrbitViewer;  
            hold(axL,'on');
        
            % satellite marker (ECI position)
            r0 = app.rI(1,:);
            app.hSat = plot3(axL, r0(1), r0(2), r0(3), 'wo', 'MarkerSize',6, 'LineWidth',1.5);
        
            % body-frame quivers at satellite position
            L = 0.15*app.rE;  % pick a visible scale for ECI plot (tune)
            C_BI = app.quat2CBI(app.q(1,:)');   
            C_IB = C_BI.';                      % body axes expressed in inertial
        
            b1 = C_IB(:,1); b2 = C_IB(:,2); b3 = C_IB(:,3);
            app.hB1 = quiver3(axL, r0(1),r0(2),r0(3), L*b1(1),L*b1(2),L*b1(3), 0, 'Color', [1 0 0]);
            app.hB2 = quiver3(axL, r0(1),r0(2),r0(3), L*b2(1),L*b2(2),L*b2(3), 0, 'Color', [0 1 0]);
            app.hB3 = quiver3(axL, r0(1),r0(2),r0(3), L*b3(1),L*b3(2),L*b3(3), 0, 'Color', [0 0 1]);
        
            % Attitude Viewer
            axB = app.AttitudeViewer;  
            cla(axB); hold(axB,'on'); axis(axB,'equal');
        
            % cache cube vertices in BODY frame (centered)
            % app.V_body = [-app.xlen/2, -app.ylen/2, -app.zlen/2;
            %               -app.xlen/2, -app.ylen/2,  app.zlen/2;
            %               -app.xlen/2,  app.ylen/2, -app.zlen/2;
            %               -app.xlen/2,  app.ylen/2,  app.zlen/2;
            %                app.xlen/2, -app.ylen/2, -app.zlen/2;
            %                app.xlen/2, -app.ylen/2,  app.zlen/2;
            %                app.xlen/2,  app.ylen/2, -app.zlen/2;
            %                app.xlen/2,  app.ylen/2,  app.zlen/2];
        
        
            % BoF to Inertial rotation
            C_BI = app.quat2CBI(app.q(1,:)');
            C_IB = C_BI.';
            Vrot = (C_IB * app.V_body.').';
        
            app.hPatch = patch(axB, 'Faces',app.F, 'Vertices',Vrot, ...
                'FaceColor', [0 0.1 0.1], 'FaceAlpha', 0.75, ...
                                   'EdgeColor', 'w');
            Rview = app.zlen;
            
            xlim(axB,[-Rview Rview])
            ylim(axB,[-Rview Rview])
            zlim(axB,[-Rview Rview])
        
            % body axes quivers at origin in BODY viewer
            Lb = 0.12;  % cube-scale
            b1 = C_IB(:,1); b2 = C_IB(:,2); b3 = C_IB(:,3);
            app.hBx = quiver3(axB, 0,0,0, Lb*b1(1),Lb*b1(2),Lb*b1(3), 1, 'Color', [1 0 0]);
            app.hBy = quiver3(axB, 0,0,0, Lb*b2(1),Lb*b2(2),Lb*b2(3), 1, 'Color', [0 1 0]);
            app.hBz = quiver3(axB, 0,0,0, Lb*b3(1),Lb*b3(2),Lb*b3(3), 1, 'Color', [0 0 1]);
        
            % ----- Timer (lightweight)
            if ~isempty(app.animTimer) && isvalid(app.animTimer)
                stop(app.animTimer); delete(app.animTimer);
            end
        
            dtau = median(diff(app.t));
            app.animTimer = timer( ...
                'ExecutionMode','fixedSpacing', ...
                'Period', (1/60), ...      
                'BusyMode','drop', ...
                'TimerFcn', @(~,~)stepAnimation(app) );
        end

        function stepAnimation(app)
                % advance index
                app.step = app.step + app.rate;
                if app.step > size(app.t,1)
                    stop(app.animTimer);
                    app.step = size(app.t,1);
                    return;
                end
                k = app.step;
            
                % current state
                r  = app.rI(k,:);
                C_BI = app.quat2CBI(app.q(k,:)');
                C_IB = C_BI.';
            
                % move satellite marker
                set(app.hSat, 'XData', r(1), 'YData', r(2), 'ZData', r(3));
            
                % update body axes quivers in orbit viewer
                L = 0.15*app.rE;
                b1 = C_IB(:,1); b2 = C_IB(:,2); b3 = C_IB(:,3);
            
                set(app.hB1, 'XData',r(1),'YData',r(2),'ZData',r(3), 'UData',L*b1(1),'VData',L*b1(2),'WData',L*b1(3));
                set(app.hB2, 'XData',r(1),'YData',r(2),'ZData',r(3), 'UData',L*b2(1),'VData',L*b2(2),'WData',L*b2(3));
                set(app.hB3, 'XData',r(1),'YData',r(2),'ZData',r(3), 'UData',L*b3(1),'VData',L*b3(2),'WData',L*b3(3));
            
                % rotate s/c body (no translation)
                Vrot = (C_IB * app.V_body.').';
                set(app.hPatch, 'Vertices', Vrot);
            
                % update attitude viewer axes quivers at origin
                Lb = 0.12;
                Lbz = 0.25;
                set(app.hBx, 'UData',Lb*b1(1),'VData',Lb*b1(2),'WData',Lb*b1(3));
                set(app.hBy, 'UData',Lb*b2(1),'VData',Lb*b2(2),'WData',Lb*b2(3));
                set(app.hBz, 'UData',Lbz*b3(1),'VData',Lbz*b3(2),'WData',Lbz*b3(3));
            
                drawnow limitrate nocallbacks
            end
        
            function CBI = quat2CBI(~,q)
                CBI =  (q(4)^2-q(1:3)'*q(1:3))*eye(3) + 2*(q(1:3)*q(1:3)')...
                        - 2*q(4)*skew(q(1:3));
            end

            function LoadDataPublicFcn(app, pathname, filename)

            waitbox = app.CustomWaitBox('Loading GUI Parameters...');

            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            else

                % Load the appState variable from the .mat file
                try
                    load(fullfile(pathname, filename), 'appState');
                    
                    % Get the list of properties from the appState struct
                    properties = fieldnames(appState);
                catch
                    ed = errordlg("The file you just tried to load does not contain the variable 'appState'. Confirm you are loading a proper GUI state file.","Error");
                    set(ed, "WindowStyle", "modal");
                    uiwait(ed);
                    return
                end

                % Loop through the properties and restore their values to the app
                for ik = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{ik}).Value = appState.(properties{ik});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end

                app.setvalues();
                close(waitbox);
            end
            end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            waitbox = app.CustomWaitBox('Setting up GUI...');
            warning('off', 'all')
            app.setvalues();
            app.drawsc();
            app.drawEarth();
            app.drawAtt();
            assignin("base", 'app', app);
            app.RunSimulationButton.Enable = 'off';
            app.PlayButton.Enable = "off";
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.Slider.Enable = "off";
            close(waitbox);

        end

        % Value changed function: XLengthmEditField
        function XLengthmEditFieldValueChanged(app, event)
            app.xlen = app.XLengthmEditField.Value;
            app.drawsc();
            app.drawAtt();
        end

        % Value changed function: YLengthmEditField
        function YLengthmEditFieldValueChanged(app, event)
            app.ylen = app.YLengthmEditField.Value;
            app.drawsc();
            app.drawAtt();
        end

        % Value changed function: ZLengthmEditField
        function ZLengthmEditFieldValueChanged(app, event)
            app.zlen = app.ZLengthmEditField.Value;
            app.drawsc();
            app.drawAtt();
        end

        % Value changed function: Ixx
        function IxxValueChanged(app, event)
            app.I_xx = app.Ixx.Value;
            app.makeJ();
        end

        % Value changed function: Iyx
        function IyxValueChanged(app, event)
            app.I_yx = app.Iyx.Value;
            app.makeJ();
        end

        % Value changed function: Izx
        function IzxValueChanged(app, event)
            app.I_zx= app.Izx.Value;
            app.makeJ();
        end

        % Value changed function: Ixy
        function IxyValueChanged(app, event)
            app.I_xy = app.Ixy.Value;
            app.makeJ();
        end

        % Value changed function: Ixz
        function IxzValueChanged(app, event)
            app.I_xz = app.Ixz.Value;
            app.makeJ();
        end

        % Value changed function: Iyy
        function IyyValueChanged(app, event)
            app.I_yy = app.Iyy.Value;
            app.makeJ();
        end

        % Value changed function: Iyz
        function IyzValueChanged(app, event)
            app.I_yz = app.Iyz.Value;
            app.makeJ();
        end

        % Value changed function: Izy
        function IzyValueChanged(app, event)
            app.I_zy = app.Izy.Value;
            app.makeJ();
        end

        % Value changed function: Izz
        function IzzValueChanged(app, event)
            app.I_zz = app.Izz.Value;
            app.makeJ();
        end

        % Button pushed function: LoadSimulinkModelButton
        function LoadSimulinkModelButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Opening Simulink diagram...');
            [app.simfile, app.location] = uigetfile('.slx');
            if isequal(app.simfile,0)
                return   % user cancelled
            end
            
            open(fullfile(app.location, app.simfile))
            close(waitbox);
            app.RunSimulationButton.Enable = 'on';
            figure(app.ADCSSimulatorUIFigure)
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Running simulation. Please be patient...');

            try
                app.simOut = sim(app.simfile);
                
                % Extracting major data for plotting
                tout = app.simOut.tout;
                assignin('base','tout',tout)
                assignin('base', "simOut", app.simOut)
    
                roll    = app.simOut.dataPacket.roll.Data;
                pitch   = app.simOut.dataPacket.pitch.Data;
                yaw     = app.simOut.dataPacket.yaw.Data;
    
                w1_body = app.simOut.dataPacket.wsc1.Data*57.2958;
                w2_body = app.simOut.dataPacket.wsc2.Data*57.2958;
                w3_body = app.simOut.dataPacket.wsc3.Data*57.2958;
    
                quat1 = app.simOut.dataPacket.q1.Data;
                quat2 = app.simOut.dataPacket.q2.Data;
                quat3 = app.simOut.dataPacket.q3.Data;
                quat4 = app.simOut.dataPacket.q4.Data;
                assignin('base','q1',quat1)
                assignin('base','q2',quat2)
                assignin('base','q3',quat3)
                assignin('base','q4',quat4)
    
                X_ECI = app.simOut.dataPacket.x_I.Data;
                Y_ECI = app.simOut.dataPacket.y_I.Data;
                Z_ECI = app.simOut.dataPacket.z_I.Data;
    
                s_x = app.simOut.dataPacket.s_x.Data;
                s_y = app.simOut.dataPacket.s_y.Data;
                s_z = app.simOut.dataPacket.s_z.Data;
    
                h1 = app.simOut.dataPacket.h1_wheels.Data*1e03;
                h2 = app.simOut.dataPacket.h2_wheels.Data*1e03;
                h3 = app.simOut.dataPacket.h3_wheels.Data*1e03;
    
                m1 = app.simOut.dataPacket.m1_cmd.Data;
                m2 = app.simOut.dataPacket.m2_cmd.Data;
                m3 = app.simOut.dataPacket.m3_cmd.Data;
    
                roll_err    = app.simOut.dataPacket.roll_err.Data;
                pitch_err   = app.simOut.dataPacket.pitch_err.Data;
                yaw_err     = app.simOut.dataPacket.yaw_err.Data;
                
                app.t     = tout(:);
                app.att   = [roll(:)  pitch(:)  yaw(:)];
                app.rates = [w1_body(:) w2_body(:) w3_body(:)];
                app.q     = [quat1(:) quat2(:) quat3(:) quat4(:)];
                app.rI    = [X_ECI(:) Y_ECI(:) Z_ECI(:)];
                app.sI    = [s_x(:) s_y(:) s_z(:)];
                app.h_w   = [h1(:) h2(:) h3(:)];
                app.magM  = [m1(:) m2(:) m3(:)];
                app.err   = [roll_err(:) pitch_err(:) yaw_err(:)];
    
                % Add static plots plotting 
                ax1 = app.AttAxes;
                plot(ax1, tout, app.q);
                xlim(ax1,[app.t(1),app.t(end)]);
    
                ax2 = app.wAxes;
                plot(ax2, tout, app.rates);
                xlim(ax2,[app.t(1),app.t(end)]);
    
                ax3 = app.MomentumAxes;
                plot(ax3, tout, app.h_w);
                xlim(ax3,[app.t(1),app.t(end)]);
    
                ax4= app.MagMomentAxes;
                plot(ax4, tout, app.magM);
                xlim(ax4,[app.t(1),app.t(end)]);

                app.PlayButton.Enable = "on";
                app.Slider.Enable = "on";
                    
                if isvalid(waitbox)
                    close(waitbox);
                end

            catch ME

                if isvalid(waitbox)
                    delete(waitbox);
                end
            
                % Build readable error message
                errMsg = sprintf( ...
                    "Simulation failed.\n\n%s\n\nLocation:\n%s (line %d)", ...
                    ME.message, ...
                    ME.stack(1).file, ...
                    ME.stack(1).line);
            
                % Show popup
                uialert(app.ADCSSimulatorUIFigure, errMsg, 'Simulation Error', ...
                    'Icon','error');
            
                return
            end       
        end

        % Value changed function: q_1EditField
        function q_1EditFieldValueChanged(app, event)
            app.q1_ini = app.q_1EditField.Value;
            assignin('base','q1_ini',app.q1_ini)
        end

        % Value changed function: q_2EditField
        function q_2EditFieldValueChanged(app, event)
            app.q2_ini = app.q_2EditField.Value;
            assignin('base','q2_ini',app.q2_ini)            
        end

        % Value changed function: q_3EditField
        function q_3EditFieldValueChanged(app, event)
            app.q3_ini= app.q_3EditField.Value;
            assignin('base','q3_ini',app.q3_ini)
        end

        % Value changed function: q_4EditField
        function q_4EditFieldValueChanged(app, event)
            app.q4_ini= app.q_4EditField.Value;
            assignin('base','q4_ini',app.q4_ini)
        end

        % Value changed function: omega_1circsEditField
        function omega_1circsEditFieldValueChanged(app, event)
            app.wB_1 = app.omega_1circsEditField.Value;
            assignin('base','wB_1',app.wB_1)
        end

        % Value changed function: omega_2circsEditField
        function omega_2circsEditFieldValueChanged(app, event)
            app.wB_2 = app.omega_2circsEditField.Value;
            assignin('base','wB_2',app.wB_2)
        end

        % Value changed function: omega_3circsEditField
        function omega_3circsEditFieldValueChanged(app, event)
            app.wB_3= app.omega_3circsEditField.Value;
            assignin('base','wB_3',app.wB_3)
        end

        % Value changed function: AltitudekmEditField
        function AltitudekmEditFieldValueChanged(app, event)
            alt = app.AltitudekmEditField.Value;
            app.a = 6378+alt;
            app.oe2rv();
            assignin('base','a',app.a)
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            app.e = app.EccentricityEditField.Value;
            app.oe2rv();
            assignin('base','e',app.e)
        end

        % Value changed function: InclinationdegEditField
        function InclinationdegEditFieldValueChanged(app, event)
            app.i= deg2rad(app.InclinationdegEditField.Value);
            app.oe2rv();
            assignin('base','i',app.i)
        end

        % Value changed function: RAANdegEditField
        function RAANdegEditFieldValueChanged(app, event)
            app.RAAN= deg2rad(app.RAANdegEditField.Value);
            app.oe2rv();
            assignin('base','RAAN',app.RAAN)
        end

        % Value changed function: ArgumentofPergieedegEditField
        function ArgumentofPergieedegEditFieldValueChanged(app, event)
            app.omega= deg2rad(app.ArgumentofPergieedegEditField.Value);
            app.oe2rv();
            assignin('base','omega',app.omega)
        end

        % Value changed function: TimeofPerigeePassagesecEditField
        function TimeofPerigeePassagesecEditFieldValueChanged(app, event)
            app.tp= app.TimeofPerigeePassagesecEditField.Value;
            app.oe2rv();
            assignin('base','tp',app.tp)
        end

        % Value changed function: CoMXEditField
        function CoMXEditFieldValueChanged(app, event)
            app.cx = app.CoMXEditField.Value;
            app.drawsc();
        end

        % Value changed function: CoMYEditField
        function CoMYEditFieldValueChanged(app, event)
            app.cy = app.CoMYEditField.Value;
            app.drawsc();
        end

        % Value changed function: CoMZEditField
        function CoMZEditFieldValueChanged(app, event)
            app.cz = app.CoMZEditField.Value;
            app.drawsc();
        end

        % Value changed function: SunSensorsigma_sdegEditField
        function SunSensorsigma_sdegEditFieldValueChanged(app, event)
            app.sigma_s= deg2rad(app.SunSensorsigma_sdegEditField.Value);
            app.buildR();
            assignin('base','sigma_S',app.sigma_s)
        end

        % Value changed function: Magnetometersigma_mdegEditField
        function Magnetometersigma_mdegEditFieldValueChanged(app, event)
            app.sigma_m = deg2rad(app.Magnetometersigma_mdegEditField.Value);
            app.buildR();
            assignin('base','sigma_B',app.sigma_m)
        end

        % Value changed function: GyroWalksigma_gradsEditField
        function GyroWalksigma_gradsEditFieldValueChanged(app, event)
            app.sigma_g = app.GyroWalksigma_gradsEditField.Value;
            assignin('base','sigma_g',app.sigma_g)
        end

        % Value changed function: GyroBiassigma_bradsEditField
        function GyroBiassigma_bradsEditFieldValueChanged(app, event)
            app.sigma_b= app.GyroBiassigma_bradsEditField.Value;
            assignin('base','sigma_b',app.sigma_b)
        end

        % Value changed function: GPSsigma_GPSdegEditField
        function GPSsigma_GPSdegEditFieldValueChanged(app, event)
            app.sigma_GPS= deg2rad(app.GPSsigma_GPSdegEditField.Value);
            assignin('base','sigmaGPS',app.sigma_GPS)
        end

        % Value changed function: WheelsAlignmentEditField
        function WheelsAlignmentEditFieldValueChanged(app, event)
            app.A_w= str2num(app.WheelsAlignmentEditField.Value);
            assignin('base','A_w',app.A_w)
        end

        % Value changed function: WheelsSpeedradsEditField
        function WheelsSpeedradsEditFieldValueChanged(app, event)
            wWheels = str2num(app.WheelsSpeedradsEditField.Value);
            app.wW1 = wWheels(1);
            app.wW2 = wWheels(2);
            app.wW3 = wWheels(3);
            assignin('base','wW_1',app.wW1)
            assignin('base','wW_2',app.wW2)
            assignin('base','wW_3',app.wW3)
        end

        % Value changed function: TorqueRodsAlignmentEditField
        function TorqueRodsAlignmentEditFieldValueChanged(app, event)
            app.A_m = str2num(app.TorqueRodsAlignmentEditField.Value);
            assignin('base','A_m',app.A_m)
        end

        % Value changed function: CoilTurnsEditField
        function CoilTurnsEditFieldValueChanged(app, event)
            app.turns = app.CoilTurnsEditField.Value;
            assignin('base','turns',app.turns)
        end

        % Value changed function: CrosssectionAream2EditField
        function CrosssectionAream2EditFieldValueChanged(app, event)
            app.ar = app.CrosssectionAream2EditField.Value;
            assignin('base','ar',app.ar)
        end

        % Value changed function: PointingModeDropDown
        function PointingModeDropDownValueChanged(app, event)
            str = app.PointingModeDropDown.Value;
            if strcmp(str, 'Nadir')
                app.pointingMode = 0;
            elseif strcmp(str, 'Sun')
                app.pointingMode= 1;
            elseif strcmp(str, 'Scheduled')
                app.pointingMode = 2;
            else
                app.pointingMode = 3;
            end
            assignin('base','pointingMode',app.pointingMode)
        end

        % Value changed function: ControlModeDropDown
        function ControlModeDropDownValueChanged(app, event)
            str = app.ControlModeDropDown.Value;
            % Logic for control selection broken
            if strcmp(str, 'PD Control')
                app.controlMode = 1;
            elseif strcmp(str, 'Bdot Control')
                app.controlMode = 2;
            elseif strcmp(str, 'Detumble')
                app.controlMode = 3;
            elseif strcmp(str, 'Desaturation')
                app.controlMode = 4;
            else
                app.controlMode = 5;
            end
            assignin('base','controlMode',app.controlMode)
        end

        % Value changed function: SimulationTimesecEditField
        function SimulationTimesecEditFieldValueChanged(app, event)
            app.tspan = app.SimulationTimesecEditField.Value;
            assignin('base','tspan',app.tspan)
        end

        % Value changed function: TimeStepsecEditField
        function TimeStepsecEditFieldValueChanged(app, event)
            app.baseRate = app.TimeStepsecEditField.Value;
            assignin('base','baseRate',app.baseRate)
        end

        % Value changed function: GNCSamplingRatesecEditField
        function GNCSamplingRatesecEditFieldValueChanged(app, event)
            app.dt = app.GNCSamplingRatesecEditField.Value;
            assignin('base','dt',app.dt)
        end

        % Value changed function: EnablenavigationfilterCheckBox
        function EnablenavigationfilterCheckBoxValueChanged(app, event)
            app.useFilter = app.EnablenavigationfilterCheckBox.Value;
            assignin('base','useFilter',app.useFilter)
        end

        % Value changed function: EnablegroundtruthvaluesCheckBox
        function EnablegroundtruthvaluesCheckBoxValueChanged(app, event)
            app.truevals = app.EnablegroundtruthvaluesCheckBox.Value;
            assignin('base','truevals',app.truevals)
        end

        % Value changed function: EnableperturbationtorquesCheckBox
        function EnableperturbationtorquesCheckBoxValueChanged(app, event)
            app.ext_torque = app.EnableperturbationtorquesCheckBox.Value;
            assignin('base','ext_torque',app.ext_torque)
        end

        % Value changed function: K_pEditField
        function K_pEditFieldValueChanged(app, event)
            app.K_p = str2num(app.K_pEditField.Value);
            assignin('base','K_p',app.K_p)
        end

        % Value changed function: K_dEditField
        function K_dEditFieldValueChanged(app, event)
            app.K_d = str2num(app.K_dEditField.Value);
            assignin('base','K_d',app.K_d)
        end

        % Value changed function: K_wEditField
        function K_wEditFieldValueChanged(app, event)
            app.K_w = app.K_wEditField.Value;
            assignin('base','K_w',app.K_w)
        end

        % Value changed function: WheelInertiakgm2EditField
        function WheelInertiakgm2EditFieldValueChanged(app, event)
            J_wheels = app.WheelInertiakgm2EditField.Value;
            assignin("base", "J_wheels", J_wheels)
        end

        % Value changed function: MasskgEditField
        function MasskgEditFieldValueChanged(app, event)
            app.mass = app.MasskgEditField.Value;
            assignin("base", "m", app.mass)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.animTimer) || ~isvalid(app.animTimer)
                initAnimation(app); % if not initialized
            end
            start(app.animTimer);
            
            app.PlayButton.Enable = "off";
            app.PauseButton.Enable = "on";
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if ~isempty(app.animTimer) && isvalid(app.animTimer)
                stop(app.animTimer);
            end

            app.StopButton.Enable = "on";
            app.PlayButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.animTimer) && isvalid(app.animTimer)
                stop(app.animTimer);
            end
            app.step = 1;
            stepAnimation(app);  % redraw frame 1 (note: step increments first)
            app.step = 1;           % restore exact 1    

            app.StopButton.Enable = "off";
            app.PauseButton.Enable = 'off';
            app.PlayButton.Enable = "on";
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.speed = app.Slider.Value;
            
        end

        % Value changed function: EpochDateDatePicker
        function EpochDateDatePickerValueChanged(app, event)
            epoch = app.EpochDateDatePicker.Value;
            app.YYYY = year(epoch);
            assignin("base", "YYYY", app.YYYY)
            app.MM   = month(epoch);
            assignin("base", "MM", app.MM)
            app.DD   = day(epoch);
            assignin("base", "DD", app.DD)
            app.dtime();
        end

        % Value changed function: EpochTimeHHMMSSEditField
        function EpochTimeHHMMSSEditFieldValueChanged(app, event)
            epochtime = str2num(app.EpochTimeHHMMSSEditField.Value);
            app.HH = epochtime(1);
            assignin("base","HH",app.HH)
            app.MIN = epochtime(2);
            assignin("base","MIN",app.MIN)
            app.SEC = epochtime(3);
            assignin("base","SEC",app.SEC)
            app.dtime();
        end

        % Button pushed function: ConfigureCoPButton
        function ConfigureCoPButtonPushed(app, event)
            figW = 350;
            figH = 320;
            
            fig = uifigure('Name','Configure CoP Matrix', ...
                           'Position',[100 100 figW figH], ...
                           'WindowStyle','normal');
            
            initData = app.CoP;
            
            % ---- Layout constants
            pad     = 10;
            btnH    = 30;
            btnW    = 80;
            gap     = 10;
            
            tableY  = pad + btnH + gap;
            tableH  = figH - tableY - pad;
            tableW  = figW - 2*pad;
            
            % ---- Table
            uit = uitable(fig, ...
                'Data', initData, ...
                'Position',[pad tableY tableW tableH], ...
                'ColumnName', {'X','Y','Z'}, ...
                'ColumnEditable', true(1,3), ...
                'ColumnFormat', {'numeric','numeric','numeric'});
            
            % ---- Buttons (bottom-right aligned)
            cancelX = figW - 2*btnW - gap - pad;
            okX     = figW - btnW - pad;
            btnY    = pad;
            
            uibutton(fig, 'Text','Cancel', ...
                'Position',[cancelX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) delete(fig));
            
            uibutton(fig, 'Text','OK', ...
                'Position',[okX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) onOK());
            
            % ---- OK callback
            function onOK()
                data = uit.Data;
            
                if isempty(data) || size(data,2) ~= 3
                    uialert(fig,'CoP must be an N×3 numeric table.','Invalid CoP');
                    return;
                end
                if any(isnan(data(:)))
                    uialert(fig,'Please fill all CoP values (no blanks).','Missing values');
                    return;
                end
            
                app.CoP = data;
                assignin("base","COP",app.CoP);
                delete(fig);
            end

        end

        % Button pushed function: ConfigureMoIButton
        function ConfigureMoIButtonPushed(app, event)
            % Unfinished!!!
            
            figW = 350;
            figH = 220;
            
            fig = uifigure('Name','Configure MoI Matrix', ...
                           'Position',[100 100 figW figH], ...
                           'WindowStyle','normal');

            initData = [0.0333 0 0; 0 0.0333 0; 0 0 0.0067];

            % ---- Layout constants
            pad     = 10;
            btnH    = 30;
            btnW    = 80;
            gap     = 10;
            
            tableY  = pad + btnH + gap;
            tableH  = figH - tableY - pad;
            tableW  = figW - 2*pad;
            
            % ---- Table
            uit = uitable(fig, ...
                'Data', initData, ...
                'Position',[pad tableY tableW tableH], ...
                'ColumnName', {'X','Y','Z'}, ...
                'ColumnEditable', true(1,3), ...
                'ColumnFormat', {'numeric','numeric','numeric'});
            
            % ---- Buttons (bottom-right aligned)
            cancelX = figW - 2*btnW - gap - pad;
            okX     = figW - btnW - pad;
            btnY    = pad;
            
            uibutton(fig, 'Text','Cancel', ...
                'Position',[cancelX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) delete(fig));
            
            uibutton(fig, 'Text','OK', ...
                'Position',[okX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) onOK());
            
            % ---- OK callback
            function onOK()
                data = uit.Data;
            
                if isempty(data) || size(data,2) ~= 3
                    uialert(fig,'CoP must be an 3×3 numeric table.','Invalid MoI');
                    return;
                end
                if any(isnan(data(:)))
                    uialert(fig,'Please fill all CoP values (no blanks).','Missing values');
                    return;
                end
            
                app.J_body = data;
                delete(fig);
            end
        end

        % Button pushed function: TunePDControllerButton
        function TunePDControllerButtonPushed(app, event)
            figW = 350;
            figH = 320;
        
            fig = uifigure('Name','PD Controller Gains', ...
                           'Position',[100 100 figW figH], ...
                           'WindowStyle','normal');
        
            % Initial values (use existing app values if you have them)
            Kp_ini = app.K_p; 
            Kd_ini = app.K_d;
        
            % ---- Layout constants
            pad   = 10;
            btnH  = 30;
            btnW  = 80;
            gap   = 10;
        
            % Bottom button row
            btnY  = pad;
        
            % Space reserved for buttons + a little gap
            contentBottom = btnY + btnH + gap;
        
            % We'll stack: label + table + gap + label + table
            labelH = 18;
            interTableGap = 8;
        
            % Compute table sizes to fit neatly
            availableH = figH - contentBottom - pad; % height above buttons
            % total fixed height from labels + gaps:
            fixedH = 2*labelH + interTableGap;
            % remaining height split between two tables
            tableH = floor((availableH - fixedH) / 2);
        
            tableW = figW - 2*pad;
        
            % Y positions (from bottom)
            kpLabelY = contentBottom + (2*tableH) + interTableGap + labelH; % not used directly
            % Let's build from top down for clarity:
            topY = figH - pad;
        
            % ---- Kp label + table
            kpLabelPos = [pad, topY - labelH, tableW, labelH];
            uilabel(fig, 'Text','Proportional gains (K_p)', ...
                         'FontWeight','bold', ...
                         'Position', kpLabelPos);
        
            kpTablePos = [pad, kpLabelPos(2) - tableH, tableW, tableH];
            uitKp = uitable(fig, ...
                'Data', Kp_ini, ...
                'Position', kpTablePos, ...
                'ColumnName', {'X','Y','Z'}, ...
                'ColumnEditable', true(1,3), ...
                'ColumnFormat', {'numeric','numeric','numeric'});
        
            % ---- Kd label + table (below Kp table)
            kdLabelPos = [pad, kpTablePos(2) - interTableGap - labelH, tableW, labelH];
            uilabel(fig, 'Text','Derivative gains (K_d)', ...
                         'FontWeight','bold', ...
                         'Position', kdLabelPos);
        
            kdTablePos = [pad, kdLabelPos(2) - tableH, tableW, tableH];
            uitKd = uitable(fig, ...
                'Data', Kd_ini, ...
                'Position', kdTablePos, ...
                'ColumnName', {'X','Y','Z'}, ...
                'ColumnEditable', true(1,3), ...
                'ColumnFormat', {'numeric','numeric','numeric'});
        
            % ---- Buttons (bottom-right aligned)
            cancelX = figW - 3*btnW - 2*gap - pad;
            applyX  = figW - 2*btnW - gap - pad;
            okX     = figW - btnW - pad;
        
            uibutton(fig, 'Text','Cancel', ...
                'Position',[cancelX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) delete(fig));
        
            uibutton(fig,'Text','Apply', ...
                'Position',[applyX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) onApply(false));
        
            uibutton(fig, 'Text','OK', ...
                'Position',[okX btnY btnW btnH], ...
                'ButtonPushedFcn', @(~,~) onApply(true));
        
            % ---- Shared validation + save
            function onApply(closeAfter)
                Kp = uitKp.Data;
                Kd = uitKd.Data;
        
                % Validate sizes
                if isempty(Kp) || ~isequal(size(Kp),[3 3]) || ~isnumeric(Kp)
                    uialert(fig,'K_p must be a 3×3 numeric matrix.','Invalid K_p');
                    return;
                end
                if isempty(Kd) || ~isequal(size(Kd),[3 3]) || ~isnumeric(Kd)
                    uialert(fig,'K_d must be a 3×3 numeric matrix.','Invalid K_d');
                    return;
                end
        
                % Validate missing values
                if any(isnan(Kp(:)))
                    uialert(fig,'Please fill all K_p values (no blanks).','Missing K_p values');
                    return;
                end
                if any(isnan(Kd(:)))
                    uialert(fig,'Please fill all K_d values (no blanks).','Missing K_d values');
                    return;
                end
        
                % Save back to app
                app.K_p = Kp;
                app.K_d = Kd;

                assignin("base","K_p",app.K_p);
                assignin("base","K_d",app.K_d);

                if closeAfter
                    delete(fig);
                end
            end
        end

        % Menu selected function: SaveGUIStateMenu
        function SaveGUIStateMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Saving GUI Parameters...');

            % Get current directory
            baseDir = pwd;
            
            % Define Saved State folder
            saveDir = fullfile(baseDir, 'Saved GUI States');
            
            % Create folder if it does not exist
            if ~exist(saveDir, 'dir')
                mkdir(saveDir);
            end
           
            [filename, pathname] = uiputfile('*.mat', 'Save As', saveDir);

            % Check if the user clicked 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)

                return

            else

                % Initialize an empty struct
                appState = struct();
                              
                % Get the list of properties from the app object
                properties = fieldnames(app);
                
                % Loop through the properties
                for ii = 1:length(properties)
                     % Try to save the 'Value' property to the struct
                    try
                        appState.(properties{ii}) = app.(properties{ii}).Value;
                    catch
                        % If the 'Value' property cannot be saved, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
                end
                save(fullfile(pathname, filename), 'appState');
            end
            close(waitbox);
        end

        % Menu selected function: LoadGUIStateMenu
        function LoadGUIStateMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Loading GUI Parameters...');

            % Get current directory
            baseDir = pwd;
            
            % Define Saved State folder
            saveDir = fullfile(baseDir, 'Saved GUI States');
            

            % Open a file browser and let the user select a .mat file
            [filename, pathname] = uigetfile('*.mat', 'Select a file', saveDir);
            
            % Call the public load function
            app.LoadDataPublicFcn(pathname,filename);

            % Close the waitbox
            close(waitbox);
        end

        % Menu selected function: ResettoDefaultStatesMenu
        function ResettoDefaultStatesMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Resetting GUI Parameters...');

            filename = 'defaultAppState.mat';
            pathname = [cd '/Misc/'];

            load(fullfile(pathname, filename), 'appState');
                    
            % Get the list of properties from the appState struct
            properties = fieldnames(appState);

            for il = 1:length(properties)
                     % Try to set the 'Value' property from the saved state
                    try
                        app.(properties{il}).Value = appState.(properties{il});
                    catch
                        % If the 'Value' property cannot be set, skip it
                        %fprintf('Skipping property %s\n', properties{i});
                    end
            end

            app.setvalues();
            close(waitbox);
        end

        % Menu selected function: GenerateMATLABPlotsMenu
        function GenerateMATLABPlotsMenuSelected(app, event)
            waitbox = app.CustomWaitBox('Plotting graphs. Please be patient...');

            if isempty(app.simOut)
                uialert(app.ADCSSimulatorUIFigure, 'Run a simulation first',...
                    'Error plotting graphs','Modal',true);
                close(waitbox)
                return
            else
                try
                    generatePlot(app)
                    close(waitbox)
                catch ME
                    uialert(app.ADCSSimulatorUIFigure, ME.message, 'Plotting failed', ...
                        'Modal', true);
                end
            end

        end

        % Menu selected function: CloseAllFiguresMenu
        function CloseAllFiguresMenuSelected(app, event)
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ADCSSimulatorUIFigure = uifigure('Visible', 'off');
            app.ADCSSimulatorUIFigure.Name = 'ADCS Simulator';
            app.FileMenu = uimenu(app.ADCSSimulatorUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveGUIStateMenu = uimenu(app.FileMenu);
            app.SaveGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveGUIStateMenuSelected, true);
            app.SaveGUIStateMenu.Text = 'Save GUI State';
            app.LoadGUIStateMenu = uimenu(app.FileMenu);
            app.LoadGUIStateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadGUIStateMenuSelected, true);
            app.LoadGUIStateMenu.Text = 'Load GUI State';
            app.ResettoDefaultStatesMenu = uimenu(app.FileMenu);
            app.ResettoDefaultStatesMenu.MenuSelectedFcn = createCallbackFcn(app, @ResettoDefaultStatesMenuSelected, true);
            app.ResettoDefaultStatesMenu.Text = 'Reset to Default States';
            app.GenerateMATLABPlotsMenu = uimenu(app.FileMenu);
            app.GenerateMATLABPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateMATLABPlotsMenuSelected, true);
            app.GenerateMATLABPlotsMenu.Text = 'Generate MATLAB Plots';
            app.ToolsMenu = uimenu(app.ADCSSimulatorUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CloseAllFiguresMenu = uimenu(app.ToolsMenu);
            app.CloseAllFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllFiguresMenuSelected, true);
            app.CloseAllFiguresMenu.Text = 'Close All Figures';
            app.GridLayout = uigridlayout(app.ADCSSimulatorUIFigure);
            app.TopTab = uitabgroup(app.GridLayout);
            app.ScenarioSetupTab = uitab(app.TopTab);
            app.ScenarioSetupTab.Title = 'Scenario Setup';
            app.GridLayout6 = uigridlayout(app.ScenarioSetupTab);
            app.TabGroup2 = uitabgroup(app.GridLayout6);
            app.BusConfigurationTab = uitab(app.TabGroup2);
            app.BusConfigurationTab.Tooltip = {'Bus Configuration'};
            app.BusConfigurationTab.Title = 'Bus Configuration';
            app.GridLayout7 = uigridlayout(app.BusConfigurationTab);
            app.BusViewer = uiaxes(app.GridLayout7);
            xlabel(app.BusViewer, 'X (m)')
            ylabel(app.BusViewer, 'Y (m)')
            zlabel(app.BusViewer, 'Z (m)')
            app.GridLayout4 = uigridlayout(app.GridLayout7);
            app.CoMXEditFieldLabel = uilabel(app.GridLayout4);
            app.CoMXEditFieldLabel.Text = 'CoM (X)';
            app.CoMXEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CoMXEditField.ValueDisplayFormat = '%.4f';
            app.CoMXEditField.ValueChangedFcn = createCallbackFcn(app, @CoMXEditFieldValueChanged, true);
            app.CoMYEditFieldLabel = uilabel(app.GridLayout4);
            app.CoMYEditFieldLabel.Text = 'CoM (Y)';
            app.CoMYEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CoMYEditField.ValueDisplayFormat = '%.4f';
            app.CoMYEditField.ValueChangedFcn = createCallbackFcn(app, @CoMYEditFieldValueChanged, true);
            app.XLengthmEditFieldLabel = uilabel(app.GridLayout4);
            app.XLengthmEditFieldLabel.Text = 'X-Length (m)';
            app.XLengthmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.XLengthmEditField.ValueDisplayFormat = '%.2f';
            app.XLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @XLengthmEditFieldValueChanged, true);
            app.XLengthmEditField.Value = 0.1;
            app.YLengthmEditFieldLabel = uilabel(app.GridLayout4);
            app.YLengthmEditFieldLabel.Text = 'Y-Length (m)';
            app.YLengthmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.YLengthmEditField.ValueDisplayFormat = '%.2f';
            app.YLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @YLengthmEditFieldValueChanged, true);
            app.YLengthmEditField.Value = 0.1;
            app.ZLengthmEditFieldLabel = uilabel(app.GridLayout4);
            app.ZLengthmEditFieldLabel.Text = 'Z-Length (m)';
            app.ZLengthmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ZLengthmEditField.ValueDisplayFormat = '%.2f';
            app.ZLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @ZLengthmEditFieldValueChanged, true);
            app.ZLengthmEditField.Value = 0.34;
            app.CoMZEditFieldLabel = uilabel(app.GridLayout4);
            app.CoMZEditFieldLabel.Text = 'CoM (Z)';
            app.CoMZEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CoMZEditField.ValueDisplayFormat = '%.4f';
            app.CoMZEditField.ValueChangedFcn = createCallbackFcn(app, @CoMZEditFieldValueChanged, true);
            app.BusPropertiesLabel = uilabel(app.GridLayout4);
            app.BusPropertiesLabel.Text = 'Bus Properties';
            app.ConfigureMoIButton = uibutton(app.GridLayout4, 'push');
            app.ConfigureMoIButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureMoIButtonPushed, true);
            app.ConfigureMoIButton.Visible = 'off';
            app.ConfigureMoIButton.Text = 'Configure MoI';
            app.GridLayout5 = uigridlayout(app.GridLayout7);
            app.Row1kgm2Label = uilabel(app.GridLayout5);
            app.Row1kgm2Label.Interpreter = 'tex';
            app.Row1kgm2Label.Text = 'Row 1 (kg/m^2)';
            app.Ixx = uieditfield(app.GridLayout5, 'numeric');
            app.Ixx.ValueChangedFcn = createCallbackFcn(app, @IxxValueChanged, true);
            app.Ixx.Value = 0.0333;
            app.Row2kgm2Label = uilabel(app.GridLayout5);
            app.Row2kgm2Label.Interpreter = 'tex';
            app.Row2kgm2Label.Text = 'Row 2 (kg/m^2)';
            app.Iyx = uieditfield(app.GridLayout5, 'numeric');
            app.Iyx.ValueChangedFcn = createCallbackFcn(app, @IyxValueChanged, true);
            app.Row3kgm2Label = uilabel(app.GridLayout5);
            app.Row3kgm2Label.Interpreter = 'tex';
            app.Row3kgm2Label.Text = 'Row 3 (kg/m^2)';
            app.Izx = uieditfield(app.GridLayout5, 'numeric');
            app.Izx.ValueChangedFcn = createCallbackFcn(app, @IzxValueChanged, true);
            app.Ixy = uieditfield(app.GridLayout5, 'numeric');
            app.Ixy.ValueChangedFcn = createCallbackFcn(app, @IxyValueChanged, true);
            app.Ixz = uieditfield(app.GridLayout5, 'numeric');
            app.Ixz.ValueChangedFcn = createCallbackFcn(app, @IxzValueChanged, true);
            app.Iyy = uieditfield(app.GridLayout5, 'numeric');
            app.Iyy.ValueChangedFcn = createCallbackFcn(app, @IyyValueChanged, true);
            app.Iyy.Value = 0.0333;
            app.Iyz = uieditfield(app.GridLayout5, 'numeric');
            app.Iyz.ValueChangedFcn = createCallbackFcn(app, @IyzValueChanged, true);
            app.Izy = uieditfield(app.GridLayout5, 'numeric');
            app.Izy.ValueChangedFcn = createCallbackFcn(app, @IzyValueChanged, true);
            app.Izz = uieditfield(app.GridLayout5, 'numeric');
            app.Izz.ValueChangedFcn = createCallbackFcn(app, @IzzValueChanged, true);
            app.Izz.Value = 0.0067;
            app.MassPropertiesLabel = uilabel(app.GridLayout5);
            app.MassPropertiesLabel.Text = 'Mass Properties';
            app.ConfigureCoPButton = uibutton(app.GridLayout5, 'push');
            app.ConfigureCoPButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureCoPButtonPushed, true);
            app.ConfigureCoPButton.Text = 'Configure CoP';
            app.MasskgEditFieldLabel = uilabel(app.GridLayout5);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MasskgEditField.ValueChangedFcn = createCallbackFcn(app, @MasskgEditFieldValueChanged, true);
            app.MasskgEditField.Value = 4;
            app.InitialConditionsTab = uitab(app.TabGroup2);
            app.InitialConditionsTab.Tooltip = {'Initial Conditions'};
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.GridLayout10 = uigridlayout(app.InitialConditionsTab);
            app.GridLayout12 = uigridlayout(app.GridLayout10);
            app.SpacecraftAttitudeandMotionLabel = uilabel(app.GridLayout12);
            app.SpacecraftAttitudeandMotionLabel.Text = 'Spacecraft Attitude and Motion';
            app.q_1EditFieldLabel = uilabel(app.GridLayout12);
            app.q_1EditFieldLabel.Interpreter = 'tex';
            app.q_1EditFieldLabel.Text = 'q_1';
            app.q_1EditField = uieditfield(app.GridLayout12, 'numeric');
            app.q_1EditField.ValueDisplayFormat = '%.4f';
            app.q_1EditField.ValueChangedFcn = createCallbackFcn(app, @q_1EditFieldValueChanged, true);
            app.q_2EditFieldLabel = uilabel(app.GridLayout12);
            app.q_2EditFieldLabel.Interpreter = 'tex';
            app.q_2EditFieldLabel.Text = 'q_2';
            app.q_2EditField = uieditfield(app.GridLayout12, 'numeric');
            app.q_2EditField.ValueDisplayFormat = '%.4f';
            app.q_2EditField.ValueChangedFcn = createCallbackFcn(app, @q_2EditFieldValueChanged, true);
            app.q_3EditFieldLabel = uilabel(app.GridLayout12);
            app.q_3EditFieldLabel.Interpreter = 'tex';
            app.q_3EditFieldLabel.Text = 'q_3';
            app.q_3EditField = uieditfield(app.GridLayout12, 'numeric');
            app.q_3EditField.ValueDisplayFormat = '%.4f';
            app.q_3EditField.ValueChangedFcn = createCallbackFcn(app, @q_3EditFieldValueChanged, true);
            app.q_4EditFieldLabel = uilabel(app.GridLayout12);
            app.q_4EditFieldLabel.Interpreter = 'tex';
            app.q_4EditFieldLabel.Text = 'q_4';
            app.q_4EditField = uieditfield(app.GridLayout12, 'numeric');
            app.q_4EditField.ValueDisplayFormat = '%.4f';
            app.q_4EditField.ValueChangedFcn = createCallbackFcn(app, @q_4EditFieldValueChanged, true);
            app.q_4EditField.Value = 1;
            app.omega_1circsLabel = uilabel(app.GridLayout12);
            app.omega_1circsLabel.Interpreter = 'tex';
            app.omega_1circsLabel.Text = '\omega_1 (\circ/s)';
            app.omega_1circsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.omega_1circsEditField.ValueDisplayFormat = '%.2f';
            app.omega_1circsEditField.ValueChangedFcn = createCallbackFcn(app, @omega_1circsEditFieldValueChanged, true);
            app.omega_2circsEditFieldLabel = uilabel(app.GridLayout12);
            app.omega_2circsEditFieldLabel.Interpreter = 'tex';
            app.omega_2circsEditFieldLabel.Text = '\omega_2 (\circ/s)';
            app.omega_2circsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.omega_2circsEditField.ValueDisplayFormat = '%.2f';
            app.omega_2circsEditField.ValueChangedFcn = createCallbackFcn(app, @omega_2circsEditFieldValueChanged, true);
            app.omega_3circsEditFieldLabel = uilabel(app.GridLayout12);
            app.omega_3circsEditFieldLabel.Interpreter = 'tex';
            app.omega_3circsEditFieldLabel.Text = '\omega_3 (\circ/s)';
            app.omega_3circsEditField = uieditfield(app.GridLayout12, 'numeric');
            app.omega_3circsEditField.ValueDisplayFormat = '%.2f';
            app.omega_3circsEditField.ValueChangedFcn = createCallbackFcn(app, @omega_3circsEditFieldValueChanged, true);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.OrbitSelectionLabel = uilabel(app.GridLayout11);
            app.OrbitSelectionLabel.Text = 'Orbit Selection';
            app.AltitudekmEditFieldLabel = uilabel(app.GridLayout11);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.GridLayout11, 'numeric');
            app.AltitudekmEditField.ValueDisplayFormat = '%.2f';
            app.AltitudekmEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudekmEditFieldValueChanged, true);
            app.AltitudekmEditField.Value = 400;
            app.EccentricityEditFieldLabel = uilabel(app.GridLayout11);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.GridLayout11, 'numeric');
            app.EccentricityEditField.ValueDisplayFormat = '%.2f';
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.InclinationdegEditFieldLabel = uilabel(app.GridLayout11);
            app.InclinationdegEditFieldLabel.Text = 'Inclination (deg)';
            app.InclinationdegEditField = uieditfield(app.GridLayout11, 'numeric');
            app.InclinationdegEditField.ValueDisplayFormat = '%.2f';
            app.InclinationdegEditField.ValueChangedFcn = createCallbackFcn(app, @InclinationdegEditFieldValueChanged, true);
            app.InclinationdegEditField.Value = 97.16;
            app.RAANdegEditFieldLabel = uilabel(app.GridLayout11);
            app.RAANdegEditFieldLabel.Text = 'RAAN (deg)';
            app.RAANdegEditField = uieditfield(app.GridLayout11, 'numeric');
            app.RAANdegEditField.ValueDisplayFormat = '%.2f';
            app.RAANdegEditField.ValueChangedFcn = createCallbackFcn(app, @RAANdegEditFieldValueChanged, true);
            app.RAANdegEditField.Value = 59.11;
            app.ArgumentofPergieedegEditFieldLabel = uilabel(app.GridLayout11);
            app.ArgumentofPergieedegEditFieldLabel.Text = {'Argument of '; 'Pergiee (deg)'};
            app.ArgumentofPergieedegEditField = uieditfield(app.GridLayout11, 'numeric');
            app.ArgumentofPergieedegEditField.ValueDisplayFormat = '%.2f';
            app.ArgumentofPergieedegEditField.ValueChangedFcn = createCallbackFcn(app, @ArgumentofPergieedegEditFieldValueChanged, true);
            app.TimeofPerigeePassagesecEditFieldLabel = uilabel(app.GridLayout11);
            app.TimeofPerigeePassagesecEditFieldLabel.Text = {'Time of Perigee '; 'Passage (sec)'};
            app.TimeofPerigeePassagesecEditField = uieditfield(app.GridLayout11, 'numeric');
            app.TimeofPerigeePassagesecEditField.ValueDisplayFormat = '%.2f';
            app.TimeofPerigeePassagesecEditField.ValueChangedFcn = createCallbackFcn(app, @TimeofPerigeePassagesecEditFieldValueChanged, true);
            app.TimeofPerigeePassagesecEditField.Value = 5552.46;
            app.HardwareTab = uitab(app.TabGroup2);
            app.HardwareTab.Tooltip = {'Hardware'};
            app.HardwareTab.Title = 'Hardware';
            app.GridLayout8 = uigridlayout(app.HardwareTab);
            app.GridLayout14 = uigridlayout(app.GridLayout8);
            app.ActuatorsLabel = uilabel(app.GridLayout14);
            app.ActuatorsLabel.Text = 'Actuators';
            app.WheelsAlignmentEditFieldLabel = uilabel(app.GridLayout14);
            app.WheelsAlignmentEditFieldLabel.Interpreter = 'tex';
            app.WheelsAlignmentEditFieldLabel.Text = {'Wheels'; ' Alignment'};
            app.WheelsAlignmentEditField = uieditfield(app.GridLayout14, 'text');
            app.WheelsAlignmentEditField.ValueChangedFcn = createCallbackFcn(app, @WheelsAlignmentEditFieldValueChanged, true);
            app.WheelsAlignmentEditField.Value = '[1,0,0;0,1,0;0,0,1]';
            app.WheelsSpeedradsEditField = uieditfield(app.GridLayout14, 'text');
            app.WheelsSpeedradsEditField.ValueChangedFcn = createCallbackFcn(app, @WheelsSpeedradsEditFieldValueChanged, true);
            app.WheelsSpeedradsEditField.Value = '[0;0;0]';
            app.WheelsSpeedLabel = uilabel(app.GridLayout14);
            app.WheelsSpeedLabel.Interpreter = 'tex';
            app.WheelsSpeedLabel.Text = {'Wheels'; 'Speed (rad/s)'};
            app.TorqueRodsAlignmentEditFieldLabel = uilabel(app.GridLayout14);
            app.TorqueRodsAlignmentEditFieldLabel.Interpreter = 'tex';
            app.TorqueRodsAlignmentEditFieldLabel.Text = {'Torque Rods'; ' Alignment'};
            app.TorqueRodsAlignmentEditField = uieditfield(app.GridLayout14, 'text');
            app.TorqueRodsAlignmentEditField.ValueChangedFcn = createCallbackFcn(app, @TorqueRodsAlignmentEditFieldValueChanged, true);
            app.TorqueRodsAlignmentEditField.Value = '[1,0,0;0,1,0;0,0,1]';
            app.CoilTurnsEditFieldLabel = uilabel(app.GridLayout14);
            app.CoilTurnsEditFieldLabel.Interpreter = 'tex';
            app.CoilTurnsEditFieldLabel.Text = 'Coil Turns';
            app.CoilTurnsEditField = uieditfield(app.GridLayout14, 'numeric');
            app.CoilTurnsEditField.ValueChangedFcn = createCallbackFcn(app, @CoilTurnsEditFieldValueChanged, true);
            app.CoilTurnsEditField.Value = 1200;
            app.CrosssectionAream2EditFieldLabel = uilabel(app.GridLayout14);
            app.CrosssectionAream2EditFieldLabel.Interpreter = 'tex';
            app.CrosssectionAream2EditFieldLabel.Text = 'Cross-section Area (m^2)';
            app.CrosssectionAream2EditField = uieditfield(app.GridLayout14, 'numeric');
            app.CrosssectionAream2EditField.ValueChangedFcn = createCallbackFcn(app, @CrosssectionAream2EditFieldValueChanged, true);
            app.CrosssectionAream2EditField.Value = 2.38e-05;
            app.WheelInertiakgm2Label = uilabel(app.GridLayout14);
            app.WheelInertiakgm2Label.Interpreter = 'tex';
            app.WheelInertiakgm2Label.Text = {'Wheel '; 'Inertia (kg m^2)'};
            app.WheelInertiakgm2EditField = uieditfield(app.GridLayout14, 'numeric');
            app.WheelInertiakgm2EditField.ValueChangedFcn = createCallbackFcn(app, @WheelInertiakgm2EditFieldValueChanged, true);
            app.WheelInertiakgm2EditField.Value = 3.81e-05;
            app.GridLayout13 = uigridlayout(app.GridLayout8);
            app.SensorsLabel = uilabel(app.GridLayout13);
            app.SensorsLabel.Text = 'Sensors';
            app.SunSensorsigma_sdegEditFieldLabel = uilabel(app.GridLayout13);
            app.SunSensorsigma_sdegEditFieldLabel.Interpreter = 'tex';
            app.SunSensorsigma_sdegEditFieldLabel.Text = {'Sun Sensor '; '\sigma_{s} (deg)'};
            app.SunSensorsigma_sdegEditField = uieditfield(app.GridLayout13, 'numeric');
            app.SunSensorsigma_sdegEditField.ValueChangedFcn = createCallbackFcn(app, @SunSensorsigma_sdegEditFieldValueChanged, true);
            app.SunSensorsigma_sdegEditField.Value = 0.01;
            app.Magnetometersigma_mdegEditFieldLabel = uilabel(app.GridLayout13);
            app.Magnetometersigma_mdegEditFieldLabel.Interpreter = 'tex';
            app.Magnetometersigma_mdegEditFieldLabel.Text = {'Magnetometer'; '\sigma_{m} (deg)'};
            app.Magnetometersigma_mdegEditField = uieditfield(app.GridLayout13, 'numeric');
            app.Magnetometersigma_mdegEditField.ValueChangedFcn = createCallbackFcn(app, @Magnetometersigma_mdegEditFieldValueChanged, true);
            app.Magnetometersigma_mdegEditField.Value = 0.001;
            app.GyroWalksigma_gradsEditFieldLabel = uilabel(app.GridLayout13);
            app.GyroWalksigma_gradsEditFieldLabel.Interpreter = 'tex';
            app.GyroWalksigma_gradsEditFieldLabel.Text = {'Gyro Walk'; '\sigma_{g} (rad/s)'};
            app.GyroWalksigma_gradsEditField = uieditfield(app.GridLayout13, 'numeric');
            app.GyroWalksigma_gradsEditField.ValueChangedFcn = createCallbackFcn(app, @GyroWalksigma_gradsEditFieldValueChanged, true);
            app.GyroWalksigma_gradsEditField.Value = 2.90888208665722e-05;
            app.GyroBiassigma_bradsEditFieldLabel = uilabel(app.GridLayout13);
            app.GyroBiassigma_bradsEditFieldLabel.Interpreter = 'tex';
            app.GyroBiassigma_bradsEditFieldLabel.Text = {'Gyro Bias'; '\sigma_{b} (rad/s)'};
            app.GyroBiassigma_bradsEditField = uieditfield(app.GridLayout13, 'numeric');
            app.GyroBiassigma_bradsEditField.ValueChangedFcn = createCallbackFcn(app, @GyroBiassigma_bradsEditFieldValueChanged, true);
            app.GyroBiassigma_bradsEditField.Value = 1.93925472443814e-07;
            app.GPSsigma_GPSdegEditFieldLabel = uilabel(app.GridLayout13);
            app.GPSsigma_GPSdegEditFieldLabel.Interpreter = 'tex';
            app.GPSsigma_GPSdegEditFieldLabel.Text = {'GPS '; '\sigma_{GPS} (deg)'};
            app.GPSsigma_GPSdegEditField = uieditfield(app.GridLayout13, 'numeric');
            app.GPSsigma_GPSdegEditField.ValueChangedFcn = createCallbackFcn(app, @GPSsigma_GPSdegEditFieldValueChanged, true);
            app.GPSsigma_GPSdegEditField.Value = 1e-06;
            app.SimulationTab = uitab(app.TabGroup2);
            app.SimulationTab.Tooltip = {'Simulation '};
            app.SimulationTab.Title = 'Simulation ';
            app.GridLayout15 = uigridlayout(app.SimulationTab);
            app.SimulationTimesecEditFieldLabel = uilabel(app.GridLayout15);
            app.SimulationTimesecEditFieldLabel.Text = 'Simulation Time (sec)';
            app.SimulationTimesecEditField = uieditfield(app.GridLayout15, 'numeric');
            app.SimulationTimesecEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesecEditFieldValueChanged, true);
            app.SimulationTimesecEditField.Value = 6000;
            app.EnablenavigationfilterCheckBox = uicheckbox(app.GridLayout15);
            app.EnablenavigationfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablenavigationfilterCheckBoxValueChanged, true);
            app.EnablenavigationfilterCheckBox.Text = 'Enable navigation filter';
            app.EnablenavigationfilterCheckBox.Value = true;
            app.EnablegroundtruthvaluesCheckBox = uicheckbox(app.GridLayout15);
            app.EnablegroundtruthvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablegroundtruthvaluesCheckBoxValueChanged, true);
            app.EnablegroundtruthvaluesCheckBox.Text = 'Enable ground truth values';
            app.EnableperturbationtorquesCheckBox = uicheckbox(app.GridLayout15);
            app.EnableperturbationtorquesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableperturbationtorquesCheckBoxValueChanged, true);
            app.EnableperturbationtorquesCheckBox.Text = 'Enable perturbation torques';
            app.EnableperturbationtorquesCheckBox.Value = true;
            app.PointingModeDropDownLabel = uilabel(app.GridLayout15);
            app.PointingModeDropDownLabel.Text = 'Pointing Mode';
            app.PointingModeDropDown = uidropdown(app.GridLayout15);
            app.PointingModeDropDown.Items = {'Nadir', 'Sun', 'Scheduled', 'Setpoint'};
            app.PointingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PointingModeDropDownValueChanged, true);
            app.PointingModeDropDown.Value = 'Nadir';
            app.ControlModeDropDownLabel = uilabel(app.GridLayout15);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.GridLayout15);
            app.ControlModeDropDown.Items = {'PD Control', 'Bdot Control', 'Detumble', 'Desaturation', 'No Control'};
            app.ControlModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlModeDropDownValueChanged, true);
            app.ControlModeDropDown.Value = 'PD Control';
            app.TimeStepsecEditFieldLabel = uilabel(app.GridLayout15);
            app.TimeStepsecEditFieldLabel.Text = 'Time Step (sec)';
            app.TimeStepsecEditField = uieditfield(app.GridLayout15, 'numeric');
            app.TimeStepsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStepsecEditFieldValueChanged, true);
            app.TimeStepsecEditField.Value = 0.5;
            app.EpochDateDatePickerLabel = uilabel(app.GridLayout15);
            app.EpochDateDatePickerLabel.Text = 'Epoch Date';
            app.EpochDateDatePicker = uidatepicker(app.GridLayout15);
            app.EpochDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EpochDateDatePickerValueChanged, true);
            app.EpochDateDatePicker.Value = datetime([2024 6 21]);
            app.EpochTimeHHMMSSEditField = uieditfield(app.GridLayout15, 'text');
            app.EpochTimeHHMMSSEditField.ValueChangedFcn = createCallbackFcn(app, @EpochTimeHHMMSSEditFieldValueChanged, true);
            app.EpochTimeHHMMSSEditField.Value = '[00,00,00]';
            app.EpochTimeHHMMSSLabel = uilabel(app.GridLayout15);
            app.EpochTimeHHMMSSLabel.Text = {'Epoch Time '; '[HH,MM,SS]'};
            app.GNCConfigurationTab = uitab(app.TabGroup2);
            app.GNCConfigurationTab.Tooltip = {'GNC Configuration'};
            app.GNCConfigurationTab.Title = 'GNC Configuration';
            app.GridLayout16 = uigridlayout(app.GNCConfigurationTab);
            app.GridLayout17_3 = uigridlayout(app.GridLayout16);
            app.ControllerTuningLabel = uilabel(app.GridLayout17_3);
            app.ControllerTuningLabel.Text = 'Controller Tuning';
            app.K_pEditField = uieditfield(app.GridLayout17_3, 'text');
            app.K_pEditField.ValueChangedFcn = createCallbackFcn(app, @K_pEditFieldValueChanged, true);
            app.K_pEditField.Value = '0.000125*[1,0,0;0,1,0;0,0,1]';
            app.K_pEditFieldLabel = uilabel(app.GridLayout17_3);
            app.K_pEditFieldLabel.Interpreter = 'tex';
            app.K_pEditFieldLabel.Text = 'K_p';
            app.K_dEditField = uieditfield(app.GridLayout17_3, 'text');
            app.K_dEditField.ValueChangedFcn = createCallbackFcn(app, @K_dEditFieldValueChanged, true);
            app.K_dEditField.Value = '0.0075*[1,0,0;0,1,0;0,0,1]';
            app.K_dEditFieldLabel = uilabel(app.GridLayout17_3);
            app.K_dEditFieldLabel.Interpreter = 'tex';
            app.K_dEditFieldLabel.Text = 'K_d';
            app.K_wEditFieldLabel = uilabel(app.GridLayout17_3);
            app.K_wEditFieldLabel.Interpreter = 'tex';
            app.K_wEditFieldLabel.Text = 'K_w';
            app.K_wEditField = uieditfield(app.GridLayout17_3, 'numeric');
            app.K_wEditField.ValueChangedFcn = createCallbackFcn(app, @K_wEditFieldValueChanged, true);
            app.K_wEditField.Value = 10000;
            app.InputthecontrollergainsforthePDcontrollerasKp1000Kp2000Kp3Label = uilabel(app.GridLayout17_3);
            app.InputthecontrollergainsforthePDcontrollerasKp1000Kp2000Kp3Label.Text = {'Input the controller gains for the PD controller as:'; ''; '[Kp1,0,0;0,Kp2,0;0,0,Kp3]'};
            app.PDControllerLabel = uilabel(app.GridLayout17_3);
            app.PDControllerLabel.Text = 'PD Controller';
            app.BdotControllerLabel = uilabel(app.GridLayout17_3);
            app.BdotControllerLabel.Text = 'Bdot Controller';
            app.NoteLabel = uilabel(app.GridLayout17_3);
            app.NoteLabel.Text = 'Note';
            app.GNCSamplingRatesecEditFieldLabel = uilabel(app.GridLayout17_3);
            app.GNCSamplingRatesecEditFieldLabel.Text = 'GNC Sampling Rate (sec)';
            app.GNCSamplingRatesecEditField = uieditfield(app.GridLayout17_3, 'numeric');
            app.GNCSamplingRatesecEditField.ValueChangedFcn = createCallbackFcn(app, @GNCSamplingRatesecEditFieldValueChanged, true);
            app.GNCSamplingRatesecEditField.Value = 0.5;
            app.TunePDControllerButton = uibutton(app.GridLayout17_3, 'push');
            app.TunePDControllerButton.ButtonPushedFcn = createCallbackFcn(app, @TunePDControllerButtonPushed, true);
            app.TunePDControllerButton.Text = 'Tune PD Controller';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.LoadSimulinkModelButton = uibutton(app.GridLayout9, 'push');
            app.LoadSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulinkModelButtonPushed, true);
            app.LoadSimulinkModelButton.Text = 'Load Simulink Model';
            app.RunSimulationButton = uibutton(app.GridLayout9, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Misc', 'Straight Logo White.png');
            app.AnimationTab = uitab(app.TopTab);
            app.AnimationTab.Title = 'Animation';
            app.AnimationGrid = uigridlayout(app.AnimationTab);
            app.GridLayout17 = uigridlayout(app.AnimationGrid);
            app.Slider = uislider(app.AnimationGrid);
            app.Slider.Limits = [0 20];
            app.Slider.MajorTicks = [0 20];
            app.Slider.MajorTickLabels = {'Slow', 'Fast'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [5 10 15];
            app.Slider.Step = 1;
            app.Slider.Visible = 'off';
            app.Slider.Value = 10;
            app.Panel_2 = uipanel(app.AnimationGrid);
            app.GridLayout18 = uigridlayout(app.Panel_2);
            app.AttitudeViewer = uiaxes(app.GridLayout18);
            xlabel(app.AttitudeViewer, 'X_I (m)')
            ylabel(app.AttitudeViewer, 'Y_I (m)')
            zlabel(app.AttitudeViewer, 'Z_I (m)')
            app.OrbitViewer = uiaxes(app.GridLayout18);
            xlabel(app.OrbitViewer, 'X_I (km)')
            ylabel(app.OrbitViewer, 'Y_I (km)')
            zlabel(app.OrbitViewer, 'Z_I (km)')
            app.AnimationControlPanel = uipanel(app.AnimationGrid);
            app.AnimationControlPanel.Title = 'Animation Control';
            app.GridLayout3 = uigridlayout(app.AnimationControlPanel);
            app.PlayButton = uibutton(app.GridLayout3, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.GridLayout3, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlotsTab = uitab(app.TopTab);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.PlotsTab);
            app.AttAxes = uiaxes(app.GridLayout2);
            title(app.AttAxes, 'Spacecraft Attitude')
            xlabel(app.AttAxes, 'Time (sec)')
            ylabel(app.AttAxes, 'q')
            zlabel(app.AttAxes, 'Z')
            app.MagMomentAxes = uiaxes(app.GridLayout2);
            title(app.MagMomentAxes, 'Command Magnetic Moment')
            xlabel(app.MagMomentAxes, 'Time (sec)')
            ylabel(app.MagMomentAxes, 'm (Am^2)')
            zlabel(app.MagMomentAxes, 'Z')
            app.MomentumAxes = uiaxes(app.GridLayout2);
            title(app.MomentumAxes, 'Wheels Angular Momentum')
            xlabel(app.MomentumAxes, 'Time (sec)')
            ylabel(app.MomentumAxes, 'h (mNms)')
            zlabel(app.MomentumAxes, 'Z')
            app.UIAxes_5 = uiaxes(app.GridLayout2);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'Time (sec)')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GridLayout2);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'Time (sec)')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.wAxes = uiaxes(app.GridLayout2);
            title(app.wAxes, 'Spacecraft Angular Velocity')
            xlabel(app.wAxes, 'Time (sec)')
            ylabel(app.wAxes, '\omega (deg/s)')
            zlabel(app.wAxes, 'Z')
            % Show the figure after all components are created
            app.ADCSSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCSSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCSSimulatorUIFigure)
        end
    end
end